classdef openpivgui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        file                            matlab.ui.container.Menu
        load                            matlab.ui.container.Menu
        exit                            matlab.ui.container.Menu
        help                            matlab.ui.container.Menu
        github                          matlab.ui.container.Menu
        wiki                            matlab.ui.container.Menu
        uipanel1                        matlab.ui.container.Panel
        pushbutton_start                matlab.ui.control.Button
        popupmenu_ittWidth              matlab.ui.control.DropDown
        popupmenu_ovlpHor               matlab.ui.control.DropDown
        popupmenu_s2ntype               matlab.ui.control.DropDown
        text13                          matlab.ui.control.Label
        text14                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        edit_s2nl                       matlab.ui.control.EditField
        text16                          matlab.ui.control.Label
        edit_scale                      matlab.ui.control.EditField
        text17                          matlab.ui.control.Label
        edit_outl                       matlab.ui.control.EditField
        text18                          matlab.ui.control.Label
        pushbutton_stop                 matlab.ui.control.Button
        edit_num                        matlab.ui.control.EditField
        edit_jump                       matlab.ui.control.EditField
        text19                          matlab.ui.control.Label
        button_select                   matlab.ui.control.Button
        button_reset_ROI                matlab.ui.control.Button
        popupmenu_ittHeight             matlab.ui.control.DropDown
        popupmenu_ovlpVer               matlab.ui.control.DropDown
        text20                          matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        checkbox_preprocess             matlab.ui.control.CheckBox
        pushbutton_select_preprocessor  matlab.ui.control.Button
        edit_dt                         matlab.ui.control.EditField
        text25                          matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        axes1                           matlab.ui.control.UIAxes
        text24                          matlab.ui.control.Label
        prev_image                      matlab.ui.control.Button
        next_image                      matlab.ui.control.Button
    end


    properties (Access = public)
        path % folder (full path) to be loaded files from
        files % list of files selected in the folder
    end
    
    methods (Access = private)
        function handles = ReadImageDirectory(app, handles)
            firstDif = find(app.files{2}-app.files{1},1,'first'); % how the first two files are different?
            app.filebase = app.files{1}(1:firstDif-1); % base file name, e.g. bird_b00100
            fileType = app.files{1}(firstDif:end-4); % without extension
            switch lower(fileType)
                case{'a','_a','b','_b'}
                    app.amount = length(app.files); % pairs
                    app.filesType = 'pairs';
                    app.step = 2;
                    set(app.edit_jump,'Enable','Off');
                    set(app.edit_jump,'String','-1');
                otherwise
                    app.amount = length(app.files);
                    app.filesType = 'sequence';
                    app.step = 1;
                    set(app.edit_jump,'Enable','On');
            end
            
            % app.filebase = app.files{1}(1:max(findstr(app.files{1},'_'))-1);
            guidata(app.figure1,handles);
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function openpiv_main_loop(app, handles, fileind, jump, cropvec, ittWidth, ittHeight, ovlapHor, ovlapVer, prepfun, s2ntype, s2nl, outl, sclt, dt)
            % OPENPIV_MAIN_LOOP is the main PIV processing, post-processing and
            % plotting subroutines calls
            
            set(app.edit_num,'string',sprintf('%d',fileind));


            [a,~,a1,b1,origin] = read_pair_of_images_rect(handles,fileind,jump,cropvec,ittWidth,ittHeight,ovlapHor,ovlapVer);
            
            % a1 = prepfun(a1);
            % b1 = prepfun(b1);
            
            [verSize,horSize]= size(a1);
            
            % Prepare the results storage;
            
            rows = 1:ovlapVer:verSize - ittHeight + 1;
            cols =  1:ovlapHor:horSize - ittWidth + 1;
            
            numcols = length(rows);
            numrows = length(cols);
            
            res = zeros(numcols*numrows,5);
            resind = 0;
            
            % a2 = zeros(ittHeight,ittWidth);
            % b2 = zeros(ittHeight,ittWidth);
            NfftWidth = 2*ittWidth;
            NfftHeight = 2*ittHeight;
            
            axes(app.axes1);
            % imshow(imadjust(a),[]);
            imshow(a,[]);
            hold on
            h1 = animatedline('Color','y');
            h2 = animatedline('Color','y');
            
            for m = rows % vertically
                for k = cols % horizontally
                    % if Stop button pressed:
                    if (get(app.pushbutton_start,'UserData') == 0)
                        return;
                    end
            
                    a2 = a1(m:m+ittHeight-1,k:k+ittWidth-1);
                    b2 = b1(m:m+ittHeight-1,k:k+ittWidth-1);
            
            
                    c = cross_correlate_rect(a2,b2,NfftHeight,NfftWidth);
                    % c = cross_correlate_rect(a2,b2,Nfftx,Nffty);
                    if ~any(c(:)), % completely "black"
                        u = 0;
                        v = 0;
                        y = origin(2) + m + ittHeight/2 - 1;
                        x = origin(1) + k + ittWidth/2 -  1;
                        continue
                    end
            
                    [peak1,peak2,pixi,pixj] = find_displacement_rect(c,s2ntype);
            
                    [peakVer,peakHor,s2n] = sub_pixel_velocity_rect(c,pixi,pixj,peak1,peak2,s2nl,ittWidth,ittHeight);
            
                    % Scale the pixel displacement to the velocity
                    u = (ittWidth-peakHor);
                    v = (ittHeight-peakVer);
                    y = origin(2) + m + ittHeight/2-1;
                    x = origin(1) + k + ittWidth/2-1;
            
                    resind = resind + 1;
                    res(resind,:) = [x y u v s2n];
                    % quiver(x+cropvec(1),y+cropvec(2),u,v,'y');
                    if u ~= 0 || v ~= 0
                        plotarrow(h1,h2,x,y,u,v,10);
                    end
                end
            end
            
            no_filt_res = res;
            
            [res, filt_res] = openpiv_filter(res,numcols,numrows,outl);
            
            % imshow(prepfun(a),[]);
            imshow(a,[]);
            hold on
            clearpoints(h1);
            clearpoints(h2);
            % for i = 1:length(res)
            %     plotarrow(h1,h2,res(i,1),res(i,2),res(i,3),res(i,4));
            % end
            quiverm(res,'color','g','AutoScaleFactor',2);
            ind = (filt_res(:,3) ~= no_filt_res(:,3) | filt_res(:,4) ~= no_filt_res(:,4));
            if ~any(ind)
                quiverm(no_filt_res(ind,:),'color','r','AutoScaleFactor',1.25);
            end
            % for i = 1:length(res)
            %     plotarrow(h1,h2,res(i,1),res(i,2),res(i,3),res(i,4));
            % end
            % drawnow
            
            basename = app.files{fileind}(1:end-4);
            baseext = '.vec';
            
            openpiv_output(res,no_filt_res,filt_res,dt,sclt,numrows, numcols, ...
                app.path,basename,baseext);
            
            hold off
        end
        
        function popupmenu1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function openpivgui_OpeningFcn(app, path)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to openpivgui (see VARARGIN)
%             
%             % Choose default command line output for openpivgui
%             app.output = hObject;
%             movegui(hObject,'center');
%             set(hObject,'Toolbar','None');
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % This sets up the initial plot - only do when we are invisible
%             % so window can get raised using openpiv_gui.
%             if strcmp(get(hObject,'Visible'),'off')
%                 im = load('openpiv_logo.mat');
%                 imshow(im.im,'Parent',findobj(hObject,'type','axes')); %app.axes_main);
%                 %     text(0.05,.1,'Matlab version of OpenPIV (originally URAPIV)','FontName','Times','Fontsize',20);
%                 %     text(0.05,.05,'http://www.openpiv.net','FontName','Verdana','Fontsize',16,'Color','blue');
%             end
%             axis off
        end

        % Button pushed function: button_reset_ROI
        function button_reset_ROI_Callback(app, event)
            % hObject    handle to button_reset_ROI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isfield(handles,'rect')
                app.rect = [];
                delete(findobj(app.axes1,'type','rectangle'))
            end
            guidata(app.figure1,handles);
        end

        % Button pushed function: button_select
        function button_select_Callback(app, event)
            % hObject    handle to button_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(app.axes1);
            app.rect = getrect;
            rectangle('position',app.rect,'Curvature',[0 0],'Edgecolor','yellow','Linestyle',':');
            guidata(app.figure1,handles);
        end

        % Value changed function: checkbox_preprocess
        function checkbox_preprocess_Callback(app, event)
            % hObject    handle to checkbox_preprocess (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_preprocess
            
            if get(hObject,'Value')
                % if checked
                set(app.pushbutton_select_preprocessor,'Enable','on');
            else
                set(app.pushbutton_select_preprocessor,'Enable','off');
            end
        end

        % Value changed function: edit_jump
        function edit_jump_Callback(app, event)
            % hObject    handle to edit_jump (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_jump as text
            %        str2double(get(hObject,'String')) returns contents of edit_jump as a double
            app.jump = str2double(get(hObject,'String')); % returns contents of edit_jump as a double
            guidata(hObject,handles);
        end

        % Value changed function: edit_outl
        function edit_outl_Callback(app, event)
            % hObject    handle to edit_outl (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_outl as text
            %        str2double(get(hObject,'String')) returns contents of edit_outl as a double
            
            app.outl = str2double(get(hObject,'String')); % returns contents of edit_outl as a double
            guidata(hObject,handles);
        end

        % Value changed function: edit_scale
        function edit_scale_Callback(app, event)
            % hObject    handle to edit_scale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_scale as text
            %        str2double(get(hObject,'String')) returns contents of edit_scale as a double
            
            app.scale = str2double(get(hObject,'String'));% returns contents of edit_scale as a double
            guidata(hObject,handles);
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            selection = questdlg(['Close ' get(app.figure1,'Name') '?'],...
                ['Close ' get(app.figure1,'Name') '...'],...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            end
            
            delete(app.figure1)
        end

        % Menu selected function: github
        function github_Callback(app, event)
            % hObject    handle to github (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            web('http://www.openpiv.net/openpiv-matlab/', '-new');
        end

        % Menu selected function: load
        function load_Callback(app, event)
            % hObject    handle to load (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [app.files,app.path] = readImDir_App;
            % set(app.edit_dir,'String',app.path);
            axes(app.axes1);
            set(app.axes1,'visible','off');
            set(app.axes1,'Units','pixels');
            
            % preprocess = get(app.checkbox_preprocess,'Value');
            % if preprocess
            %     prepfun = str2func(app.preprocess);
            % else
            %     prepfun = inline('imadjust(x)');
            % end
            %
            % try
            %     % imshow(fullfile(app.path,app.files{1}));
            %     tmp = imread(fullfile(app.path,app.files{1}));
            %     if length(size(tmp)) == 3
            %         tmp = rgb2gray(tmp);
            %     end
            %     imshow(prepfun(tmp));
            % catch
            %     tmp = tiffread2(fullfile(app.path,app.files{1}));
            %     tmp = im2double(tmp.data);
            %     imshow(prepfun(tmp));
            % end
            
            im = openpiv_imread(handles,1);
            imshow(im);
            set(app.prev_image,'Visible','On');
            set(app.next_image,'Visible','On');
            handles = ReadImageDirectory(handles);
            % set(app.edit_num,'string',sprintf('%d/%d',1,app.amount));
            guidata(app.figure1, handles);
        end

        % Button pushed function: next_image
        function next_image_Callback(app, event)
            % hObject    handle to next_image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(app.axes1);
            filenum = str2num(get(app.edit_num,'String'));
            filenum = min(filenum + 1,length(app.files));
            set(app.edit_num,'String',int2str(filenum));
            imshow(openpiv_imread(handles,filenum));
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu_ittWidth
        function popupmenu_ittWidth_Callback(app, event)
            % hObject    handle to popupmenu_ittWidth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_ittWidth contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_ittWidth
            
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end

        % Value changed function: popupmenu_s2ntype
        function popupmenu_s2ntype_Callback(app, event)
            % hObject    handle to popupmenu_s2ntype (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_s2ntype contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_s2ntype
            
            contents = get(hObject,'String');%  returns popupmenu_s2ntype contents as cell array
            app.s2ntype = str2double(contents{get(hObject,'Value')});% returns selected item from popupmenu_s2ntype
            guidata(hObject,handles);
        end

        % Button pushed function: prev_image
        function prev_image_Callback(app, event)
            % hObject    handle to prev_image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(app.axes1);
            filenum = str2num(get(app.edit_num,'String'));
            filenum = max(1,filenum - 1);
            set(app.edit_num,'String',int2str(filenum));
            % imshow(fullfile(app.path,app.files{1}));
            imshow(openpiv_imread(handles,filenum));
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_select_preprocessor
        function pushbutton_select_preprocessor_Callback(app, event)
            % hObject    handle to pushbutton_select_preprocessor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            preprocess_mfile = uigetfile('*.m','Pick an M-file');
            app.preprocess = preprocess_mfile(1:end-2);
            guidata(app.figure1,handles);
        end

        % Button pushed function: pushbutton_start
        function pushbutton_start_Callback(app, event)
            % hObject    handle to pushbutton_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(app.pushbutton_start,'UserData',1);
            
            contents = get(app.popupmenu_ittWidth,'String');
            ittWidth = str2double(contents{get(app.popupmenu_ittWidth,'Value')});
            
            contents = get(app.popupmenu_ittHeight,'String');
            ittHeight = str2double(contents{get(app.popupmenu_ittHeight,'Value')});
            
            contents = get(app.popupmenu_ovlpHor,'String');
            ovlapHor = str2double(contents{get(app.popupmenu_ovlpHor,'Value')});
            
            contents = get(app.popupmenu_ovlpVer,'String');
            ovlapVer = str2double(contents{get(app.popupmenu_ovlpVer,'Value')});
            
            s2ntype = get(app.popupmenu_s2ntype,'value');
            s2nl = str2double(get(app.edit_s2nl,'string'));
            sclt = str2double(get(app.edit_scale,'string'));
            dt = str2double(get(app.edit_dt,'string'));
            outl = str2double(get(app.edit_outl,'string'));
            
            preprocess = get(app.checkbox_preprocess,'Value');
            if preprocess
                if ~isfield(handles,'preprocess')
                    prepfun = inline('x');
                else
                    prepfun = str2func(app.preprocess);
                end
            else
                prepfun = inline('x');
            end
            
            if isfield(handles,'rect') && ~isempty(app.rect)
                cropvec = app.rect;
            else
                cropvec = [0 0 0 0];
            end
            jump = str2double(get(app.edit_jump,'string'));
            if jump == -1
                jump = 0;
            end
            
            % Main loop
            set(app.figure1,'pointer','watch')
            
            %
            % image1 = fullfile(app.path,app.files{1});
            % image2 = fullfile(app.path,app.files{2});
            
            [a,b] = read_pair_of_images_rect(handles,1,1,cropvec,ittWidth,ittHeight,ovlapHor,ovlapVer);
            if isempty(a) || isempty(b)
                errordlg('Something wrong with your images')
            end
            
            switch app.filesType
                case{'sequence'}
                    for fileind = 1:app.amount-jump	% main loop, for whole file list
                        openpiv_main_loop(handles, fileind, jump, cropvec,ittWidth,...
                ittHeight,ovlapHor,ovlapVer, prepfun, s2ntype, s2nl, outl, sclt, dt)
                    end
                case{'pairs'}
                    for fileind = 1:2:app.amount	% main loop, for whole file list
                        openpiv_main_loop(handles, fileind, 1, cropvec,ittWidth,...
                ittHeight,ovlapHor,ovlapVer, prepfun, s2ntype, s2nl, outl, sclt, dt)
                    end
                otherwise
            
            end
            % end
            set(app.figure1,'pointer','arrow')
            guidata(app.figure1,handles);
        end

        % Button pushed function: pushbutton_stop
        function pushbutton_stop_Callback(app, event)
            % hObject    handle to pushbutton_stop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            selection = questdlg(['Stop ' get(app.figure1,'Name') '?'],...
                ['Close ' get(app.figure1,'Name') '...'],...
                'Yes','No','Yes');
            if strcmp(selection,'No')
                return;
            elseif strcmp(selection,'Yes')
                set(app.pushbutton_start,'UserData',0);
                set(app.figure1,'pointer','arrow');
            end
            % delete(app.figure1)
            guidata(hObject,handles);
        end

        % Menu selected function: wiki
        function wiki_Callback(app, event)
            % hObject    handle to wiki (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            web('http://www.openpiv.net/faq.html', '-new');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'OpenPIV Matlab App (2018a version)';
            app.file = uimenu(app.figure1);
            app.file.Text = 'File';
            app.load = uimenu(app.file);
            app.load.MenuSelectedFcn = createCallbackFcn(app, @load_Callback, true);
            app.load.Accelerator = 'L';
            app.load.Text = 'Load';
            app.exit = uimenu(app.file);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Separator = 'on';
            app.exit.Accelerator = 'X';
            app.exit.Text = 'Exit';
            app.help = uimenu(app.figure1);
            app.help.Text = 'Help';
            app.github = uimenu(app.help);
            app.github.MenuSelectedFcn = createCallbackFcn(app, @github_Callback, true);
            app.github.Text = 'Website';
            app.wiki = uimenu(app.help);
            app.wiki.MenuSelectedFcn = createCallbackFcn(app, @wiki_Callback, true);
            app.wiki.Text = 'FAQ';
            app.uipanel1 = uipanel(app.figure1);
            app.pushbutton_start = uibutton(app.uipanel1, 'push');
            app.pushbutton_start.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_start_Callback, true);
            app.pushbutton_start.Text = 'START';
            app.popupmenu_ittWidth = uidropdown(app.uipanel1);
            app.popupmenu_ittWidth.Items = {'8', '16', '32', '64', '128', '256', '512', '1024'};
            app.popupmenu_ittWidth.ValueChangedFcn = createCallbackFcn(app, @popupmenu_ittWidth_Callback, true);
            app.popupmenu_ittWidth.Value = '32';
            app.popupmenu_ovlpHor = uidropdown(app.uipanel1);
            app.popupmenu_ovlpHor.Items = {'8', '16', '32', '64', '128', '256', '512', '1024'};
            app.popupmenu_ovlpHor.Value = '32';
            app.popupmenu_s2ntype = uidropdown(app.uipanel1);
            app.popupmenu_s2ntype.Items = {'1', '2'};
            app.popupmenu_s2ntype.ValueChangedFcn = createCallbackFcn(app, @popupmenu_s2ntype_Callback, true);
            app.popupmenu_s2ntype.Value = '2';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = 'Spacing/Overlap';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'Interrogation window size';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Text = 'S/N type';
            app.edit_s2nl = uieditfield(app.uipanel1, 'text');
            app.edit_s2nl.Value = '1';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Text = 'S/N value';
            app.edit_scale = uieditfield(app.uipanel1, 'text');
            app.edit_scale.ValueChangedFcn = createCallbackFcn(app, @edit_scale_Callback, true);
            app.edit_scale.Value = '0';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Text = 'Scale (m/pix)';
            app.edit_outl = uieditfield(app.uipanel1, 'text');
            app.edit_outl.ValueChangedFcn = createCallbackFcn(app, @edit_outl_Callback, true);
            app.edit_outl.Value = '100';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Text = 'Outlier filter';
            app.pushbutton_stop = uibutton(app.uipanel1, 'push');
            app.pushbutton_stop.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_stop_Callback, true);
            app.pushbutton_stop.Text = 'STOP';
            app.edit_num = uieditfield(app.uipanel1, 'text');
            app.edit_num.Value = '1';
            app.edit_jump = uieditfield(app.uipanel1, 'text');
            app.edit_jump.ValueChangedFcn = createCallbackFcn(app, @edit_jump_Callback, true);
            app.edit_jump.Value = '1';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Text = 'Jump';
            app.button_select = uibutton(app.uipanel1, 'push');
            app.button_select.ButtonPushedFcn = createCallbackFcn(app, @button_select_Callback, true);
            app.button_select.Text = 'Select ROI';
            app.button_reset_ROI = uibutton(app.uipanel1, 'push');
            app.button_reset_ROI.ButtonPushedFcn = createCallbackFcn(app, @button_reset_ROI_Callback, true);
            app.button_reset_ROI.Text = 'Reset ROI';
            app.popupmenu_ittHeight = uidropdown(app.uipanel1);
            app.popupmenu_ittHeight.Items = {'8', '16', '32', '64', '128', '256', '512', '1024'};
            app.popupmenu_ittHeight.Value = '32';
            app.popupmenu_ovlpVer = uidropdown(app.uipanel1);
            app.popupmenu_ovlpVer.Items = {'8', '16', '32', '64', '128', '256', '512', '1024'};
            app.popupmenu_ovlpVer.Value = '32';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Text = 'Width';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Text = 'Height';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Text = 'Horizontal';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'Vertical';
            app.checkbox_preprocess = uicheckbox(app.uipanel1);
            app.checkbox_preprocess.ValueChangedFcn = createCallbackFcn(app, @checkbox_preprocess_Callback, true);
            app.checkbox_preprocess.Text = 'Preprocessing';
            app.pushbutton_select_preprocessor = uibutton(app.uipanel1, 'push');
            app.pushbutton_select_preprocessor.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_select_preprocessor_Callback, true);
            app.pushbutton_select_preprocessor.Enable = 'off';
            app.pushbutton_select_preprocessor.Text = 'Select preprocessor';
            app.edit_dt = uieditfield(app.uipanel1, 'text');
            app.edit_dt.Value = '0';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Text = 'dt (sec)';
            app.uipanel2 = uipanel(app.figure1);
            app.axes1 = uiaxes(app.uipanel2);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.XTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes1.YTickLabel = {'0  '; '0.2'; '0.4'; '0.6'; '0.8'; '1  '};
            app.axes1.ZTick = [0 0.5 1];
            app.axes1.ZTickLabel = '';
            app.text24 = uilabel(app.uipanel2);
            app.text24.Text = 'Copyright (c) 1999-2016 OpenPIV (www.openpiv.net)';
            app.prev_image = uibutton(app.uipanel2, 'push');
            app.prev_image.ButtonPushedFcn = createCallbackFcn(app, @prev_image_Callback, true);
            app.prev_image.Visible = 'off';
            app.prev_image.Text = '<<';
            app.next_image = uibutton(app.uipanel2, 'push');
            app.next_image.ButtonPushedFcn = createCallbackFcn(app, @next_image_Callback, true);
            app.next_image.Visible = 'off';
            app.next_image.Text = '>>';
        end
    end

    methods (Access = public)

        % Construct app
        function app = openpivgui_App(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)openpivgui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end