classdef readImDir_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fig                matlab.ui.Figure
        pushbutton_load    matlab.ui.control.Button
        pushbutton_cancel  matlab.ui.control.Button
        listbox_files      matlab.ui.control.ListBox
        edit_path          matlab.ui.control.EditField
        pushbutton_cd      matlab.ui.control.Button
        pushbutton_updir   matlab.ui.control.Button
    end


    properties (Access = private)
        files = {}; % default list of files
    end

    properties (Access = public)
        selected_files = {}; % released selected_files
        path = '';  % default path
    end

    methods (Access = private)
        function edit_scale_Callback(app, hObject, handles)
            % hObject    handle to edit_scale (see GCBO) eventdata  reserved - to be
            % defined in a future version of MATLAB handles    structure with handles
            % and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_scale as text
            %        str2double(get(hObject,'String')) returns contents of edit_scale
            %        as a double
            if isnan(str2double(get(hObject,'String')))
                set(hObject,'String','1');
            end
            app.scale = str2double(get(hObject,'String'));
            guidata(hObject,handles);
        end
        
        function jump_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to jump (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function update_gui(app, handles)
            % Self made UPDATE GUI function
            image_files = {'jpg','bmp','jpeg','tif','tiff','png'};
            if ~isfolder(app.path)
                app.path = cd;
            end
            
            set(app.edit_path,'Value',app.path);
            list = dir(app.path);
            app.files = {};
            for i = 1:length(list)
                if ismember(lower(getext(list(i).name)),image_files)
                    app.files = cat(1,app.files,list(i).name);
                end
            end
            %     app.files = dir(fullfile(app.path,'*.bmp'));
            %     app.files = cat(1,app.files,dir(fullfile(app.path,'*.jpg')));
            %     app.files = cat(1,app.files,dir(fullfile(app.path,'*.jpeg')));
            %     app.files = cat(1,app.files,dir(fullfile(app.path,'*.tif')));
            %     app.files = cat(1,app.files,dir(fullfile(app.path,'*.tiff')));
            %     app.files = cat(1,app.files,dir(fullfile(app.path,'*.png')));
            
            % list = dir(app.path);
            subfolders = {};
            for i = 1:length(list)
                if isfolder(list(i).name)
                    subfolders = cat(1,subfolders,list(i).name);
                end
            end
            set(app.fig,'SelectionType','normal');
            set(app.listbox_files,'Items',cat(1,subfolders,app.files));
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function readImDir_OpeningFcn(app)
%             
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to readImDir (see VARARGIN)
%             
%             % Choose default command line output for readImDir
%             
%             handles = guihandles;
%             app.output = hObject;
%             
%             if exist('lastpath.mat','file')
%                 load('lastpath.mat');
%                 if exist('lastpath','var') && exist(lastpath,'dir')
%                     app.path = lastpath;
%                 else
%                     app.path = pwd;
%                 end
%             else
%                 app.path = pwd;
%             end
%             
%             app.state3d = 0;
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             update_gui(hObject,[],handles);
%             % %
%             % % % UIWAIT makes readImDir wait for user response (see %uiresume)
%             uiwait(app.fig);
        end

        % Value changed function: edit_path
        function edit_path_Callback(app, event)
            % hObject    handle to edit_path (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_path as text
            %        str2double(get(hObject,'String')) returns contents of edit_path as a double
            
            tmp = get(app.edit_path,'Text');
            if isfolder(tmp)
                app.path = tmp;
            else
                set(app.edit_path,'Text',app.path);
            end
            update_gui(app, [], handles);
        end

        % Value changed function: listbox_files
        function listbox_files_Callback(app, event)
            
            if strcmp(get(app.fig,'SelectionType'),'open') % If double click
                index_selected = get(app.listbox_files,'Value');
                file_list = get(app.listbox_files,'Items');
                filename = file_list{index_selected};       % Item selected in list box
                %     keyboard
                if  isfolder([app.path,filesep,filename]) % If directory
                    if index_selected == 2
                        app.path = app.path(1:max(findstr(app.path,filesep)-1));
                    elseif index_selected > 2
                        app.path = [app.path,filesep,filename];
                    end
                end
            end
        end

        % Button pushed function: pushbutton_cancel
        function pushbutton_cancel_Callback(app, event)
            % hObject    handle to pushbutton_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            uiresume(app.fig);
        end

        % Button pushed function: pushbutton_cd
        function pushbutton_cd_Callback(app, event)
            % hObject    handle to pushbutton_cd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmp = uigetdir(app.path);
            if isfolder(tmp)
                app.path = tmp; 
            end
            set(app.edit_path,'Value',app.path);
            update_gui(app);
        end

        % Button pushed function: pushbutton_load
        function pushbutton_load_Callback(app, event)
            % hObject    handle to pushbutton_load (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Returns the names of the selected files
            app.selected_files = get(app.listbox_files,'Items');
%             index_selected = get(app.listbox_files,'Value');
%             % jump = int32(str2num(get(app.jump,'String')));
%             jump = 1;
%             if isempty(index_selected) || min(index_selected) < 3
%                 errordlg('Wrong selection','Incorrect Selection','modal')
%             else
%             
%                 switch length(index_selected)
%                     case {1}                        % only the first file is selected,
%                         index = index_selected;
%                         app.filenames = list_entries(index(1:jump:end));
%                     case {2}
%                         index = app.index_selected; %
%                         app.filenames = list_entries(index(1:jump:end));
%             
%                     otherwise
%                         app.filenames = app.list_entries(app.index_selected(1:jump:end));
%             
%                 end
%             
%                 try
%                     lastpath = app.path;
%             
%                     save('lastpath.mat','lastpath');
%                 catch
%                     ;
%                 end
%             
%                 guidata(hObject,handles);
%                 uiresume(app.fig);
%            end
        end

        % Button pushed function: pushbutton_updir
        function pushbutton_updir_Callback(app, event)
            % hObject    handle to pushbutton_updir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            s = app.path;
            s = s(1:max(3,max(findstr(s,filesep))-1));
            if exist(s,'dir')
                app.path = s;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fig = uifigure;
            app.fig.Name = 'Select images: (single, pair, Shift+)';
            app.pushbutton_load = uibutton(app.fig, 'push');
            app.pushbutton_load.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_Callback, true);
            app.pushbutton_load.Text = 'Load';
            app.pushbutton_cancel = uibutton(app.fig, 'push');
            app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
            app.pushbutton_cancel.Text = 'Cancel';
            app.listbox_files = uilistbox(app.fig);
            app.listbox_files.Items = {};
            app.listbox_files.Multiselect = 'on';
            app.listbox_files.ValueChangedFcn = createCallbackFcn(app, @listbox_files_Callback, true);
            app.listbox_files.Value = {};
            app.edit_path = uieditfield(app.fig, 'text');
            app.edit_path.ValueChangedFcn = createCallbackFcn(app, @edit_path_Callback, true);
            app.edit_path.Value = '/Users/alex/Documents/OpenPIV/openpiv-matlab';
            app.pushbutton_cd = uibutton(app.fig, 'push');
            app.pushbutton_cd.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cd_Callback, true);
            app.pushbutton_cd.Text = '...';
            app.pushbutton_updir = uibutton(app.fig, 'push');
            app.pushbutton_updir.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_updir_Callback, true);
            app.pushbutton_updir.Icon = 'pushbutton_updir_image.png';
            app.pushbutton_updir.Visible = 'off';
            app.pushbutton_updir.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = readImDir_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fig)

            % Execute the startup function
            runStartupFcn(app, @readImDir_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fig)
        end
    end
end